classdef ICIBICI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MATCHPASSWORDButton             matlab.ui.control.Button
        SaveDataPanel                   matlab.ui.container.Panel
        SAVEButton                      matlab.ui.control.Button
        FolderEditField                 matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        CLEARDATAButton                 matlab.ui.control.Button
        RECORDButton                    matlab.ui.control.StateButton
        ViewingParametersPanel          matlab.ui.container.Panel
        max_segment_label               matlab.ui.control.Label
        SegmentNumberEditField          matlab.ui.control.NumericEditField
        SegmentNumberEditFieldLabel     matlab.ui.control.Label
        SegmentSlider                   matlab.ui.control.Slider
        FrequencyMaxLimitHzEditField    matlab.ui.control.NumericEditField
        FrequencyMaxLimitHzEditFieldLabel  matlab.ui.control.Label
        RecordingParameterPanel         matlab.ui.container.Panel
        SegmentLengthsEditField         matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel    matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        VerticalResolutionbitsDropDown  matlab.ui.control.DropDown
        VerticalResolutionbitsDropDownLabel  matlab.ui.control.Label
        FFT_axes                        matlab.ui.control.UIAxes
        Raw_data_axes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nBits % Description
        Fs
        recording_length
        nChannels

        recorder

        raw_data
        fft_max_xlim

        sample_pts

        segment_number
        max_segment_number
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Raw_data_axes.Title.String = 'Raw Data';
            app.Raw_data_axes.XLabel.String = 'Time (s)';
            app.Raw_data_axes.YLabel.String = 'Voltage (V)';

            app.Raw_data_axes.Title.String = 'Frequency Plot';
            app.FFT_axes.XLabel.String = 'Frequency (Hz)';
            app.FFT_axes.YLabel.String = 'Abs[FFT] (a.u.)';

            app.nBits = 16;
            app.Fs = 8000;
            app.nChannels = 1;

            app.recorder = audiorecorder(app.Fs,app.nBits,app.nChannels);

            app.fft_max_xlim = 50;

            app.segment_number = 1;

        end

        % Value changed function: VerticalResolutionbitsDropDown
        function VerticalResolutionbitsDropDownValueChanged(app, event)
            app.nBits = str2double(app.VerticalResolutionbitsDropDown.Value);
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            app.Fs = ceil(app.SamplingFrequencyHzEditField.Value / 1000) *1000;
            app.SamplingFrequencyHzEditField.Value = app.Fs;
        end

        % Value changed function: SegmentLengthsEditField
        function SegmentLengthsEditFieldValueChanged(app, event)
            app.recording_length = round(app.SegmentLengthsEditField.Value);
        end

        % Value changed function: RECORDButton
        function RECORDButtonValueChanged(app, event)
            pressed = app.RECORDButton.Value;

            app.RECORDButton.Text = "STOP";

            app.SegmentNumberEditField.Editable = "off";
            app.SegmentNumberEditField.Enable = "off";

            app.SegmentSlider.Enable = "off";
            
            app.VerticalResolutionbitsDropDownValueChanged;
            app.SamplingFrequencyHzEditFieldValueChanged;
            app.SegmentLengthsEditFieldValueChanged;

            recorder = audiorecorder(app.Fs,app.nBits,app.nChannels);
            
            app.sample_pts = app.Fs * app.recording_length;
            t = (0:app.sample_pts - 1) / app.Fs;
            f = (0: app.sample_pts/2 -1) * app.Fs / app.sample_pts;

            lol = false;

            while pressed
                lol = true;
                recordblocking(recorder, app.recording_length);
                y = getaudiodata(recorder);
                fft_y = fft(y(1: app.sample_pts/2, :));

                if lol
                    app.raw_data = cat(2, app.raw_data, y);
                else
                    app.raw_data = y;
                end
                    

                plot(app.Raw_data_axes, t, y, 'b');
                plot(app.FFT_axes, f, fft_y, 'r');
                xlim(app.FFT_axes, [0, app.fft_max_xlim])

                pressed = app.RECORDButton.Value;
            end

            app.max_segment_number = size(app.raw_data, 2);

            app.max_segment_label.Text = '/' + string(app.max_segment_number);
            app.max_segment_label.Visible = "on";
            app.max_segment_label.Enable = "on";

            app.SegmentNumberEditField.Limits = [1, app.max_segment_number];
            app.SegmentNumberEditField.Value = app.max_segment_number;
            app.SegmentNumberEditField.Visible = "on";
            app.SegmentNumberEditField.Editable = "on";
            app.SegmentNumberEditField.Enable = "on";
            app.SegmentNumberEditFieldLabel.Visible = "on";
            app.SegmentNumberEditFieldLabel.Enable = "on";


            app.SegmentSlider.Limits = [1, app.max_segment_number];
            app.SegmentSlider.Value = app.max_segment_number;
            app.SegmentSlider.Visible = "on";
            app.SegmentSlider.Enable = "on";

            app.RECORDButton.Text = "RECORD";
        end

        % Value changed function: FrequencyMaxLimitHzEditField
        function FrequencyMaxLimitHzEditFieldValueChanged(app, event)
            app.fft_max_xlim = round(app.FrequencyMaxLimitHzEditField.Value);
        end

        % Value changed function: SegmentNumberEditField
        function SegmentNumberEditFieldValueChanged(app, event)
            app.segment_number = round(app.SegmentNumberEditField.Value);
            app.SegmentNumberEditField.Value = app.segment_number;
            app.SegmentSlider.Value = app.segment_number;

            t = (0:app.sample_pts - 1) / app.Fs;
            f = (0: app.sample_pts/2 -1) * app.Fs / app.sample_pts;
            
            y = app.raw_data(:, app.segment_number);
            fft_y = fft(y(1: app.sample_pts/2, :));

            plot(app.Raw_data_axes, t, y, 'b');
            plot(app.FFT_axes, f, fft_y, 'r');
            xlim(app.FFT_axes, [0, app.fft_max_xlim]);
        end

        % Value changed function: SegmentSlider
        function SegmentSliderValueChanged(app, event)
            app.segment_number = round(app.SegmentSlider.Value);
            app.SegmentNumberEditField.Value = app.segment_number;
            app.SegmentSlider.Value = app.segment_number;

            t = (0:app.sample_pts - 1) / app.Fs;
            f = (0: app.sample_pts/2 -1) * app.Fs / app.sample_pts;
            
            y = app.raw_data(:, app.segment_number);
            fft_y = fft(y(1: app.sample_pts/2, :));

            plot(app.Raw_data_axes, t, y, 'b');
            plot(app.FFT_axes, f, fft_y, 'r');
            xlim(app.FFT_axes, [0, app.fft_max_xlim]);
        end

        % Button pushed function: CLEARDATAButton
        function CLEARDATAButtonPushed(app, event)
            app.raw_data = [];

            cla(app.FFT_axes);
            cla(app.Raw_data_axes);

            app.max_segment_label.Visible = "off";
            app.max_segment_label.Enable = "off";

            app.SegmentNumberEditField.Visible = "off";
            app.SegmentNumberEditField.Editable = "off";
            app.SegmentNumberEditField.Enable = "off";
            app.SegmentNumberEditFieldLabel.Visible = "off";
            app.SegmentNumberEditFieldLabel.Enable = "off";


            app.SegmentSlider.Visible = "off";
            app.SegmentSlider.Enable = "off";


        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            folder = app.FolderEditField.Value;

            d = replace(string(datetime), ' ', '_') + '__Recording.mat';
            filename = fullfile(folder, d);

            Recording = struct();

            Recording.raw_data = app.raw_data;
            Recording.Fs.Value = app.Fs;
            Recording.Fs.Unit = 'Hz';

            Recording.recording_length.Value = app.recording_length;
            Recording.recording_length.Unit = 's';

            Recording.Vertical_Resolution.Value = app.nBits;
            Recording.Vertical_Resolution.Unit = 'bits';

            save(filename, "Recording");


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Raw_data_axes = uiaxes(app.UIFigure);
            title(app.Raw_data_axes, 'Title')
            xlabel(app.Raw_data_axes, 'X')
            ylabel(app.Raw_data_axes, 'Y')
            zlabel(app.Raw_data_axes, 'Z')
            app.FFT_axes = uiaxes(app.UIFigure);
            title(app.FFT_axes, 'Title')
            xlabel(app.FFT_axes, 'X')
            ylabel(app.FFT_axes, 'Y')
            zlabel(app.FFT_axes, 'Z')
            app.RecordingParameterPanel = uipanel(app.UIFigure);
            app.RecordingParameterPanel.Title = 'Recording Parameter';
            app.VerticalResolutionbitsDropDownLabel = uilabel(app.RecordingParameterPanel);
            app.VerticalResolutionbitsDropDownLabel.Text = 'Vertical Resolution (bits)';
            app.VerticalResolutionbitsDropDown = uidropdown(app.RecordingParameterPanel);
            app.VerticalResolutionbitsDropDown.Items = {'8', '16', '24'};
            app.VerticalResolutionbitsDropDown.ValueChangedFcn = createCallbackFcn(app, @VerticalResolutionbitsDropDownValueChanged, true);
            app.VerticalResolutionbitsDropDown.Value = '16';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.RecordingParameterPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.RecordingParameterPanel, 'numeric');
            app.SamplingFrequencyHzEditField.Limits = [0 20000];
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 8000;
            app.SegmentLengthsEditFieldLabel = uilabel(app.RecordingParameterPanel);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLengthsEditField = uieditfield(app.RecordingParameterPanel, 'numeric');
            app.SegmentLengthsEditField.Limits = [0 10];
            app.SegmentLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentLengthsEditFieldValueChanged, true);
            app.SegmentLengthsEditField.Value = 2;
            app.ViewingParametersPanel = uipanel(app.UIFigure);
            app.ViewingParametersPanel.Title = 'Viewing Parameters';
            app.FrequencyMaxLimitHzEditFieldLabel = uilabel(app.ViewingParametersPanel);
            app.FrequencyMaxLimitHzEditFieldLabel.Text = 'Frequency Max Limit (Hz)';
            app.FrequencyMaxLimitHzEditField = uieditfield(app.ViewingParametersPanel, 'numeric');
            app.FrequencyMaxLimitHzEditField.Limits = [0 4000];
            app.FrequencyMaxLimitHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMaxLimitHzEditFieldValueChanged, true);
            app.FrequencyMaxLimitHzEditField.Value = 50;
            app.SegmentSlider = uislider(app.ViewingParametersPanel);
            app.SegmentSlider.MajorTicks = [];
            app.SegmentSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentSliderValueChanged, true);
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Enable = 'off';
            app.SegmentSlider.Visible = 'off';
            app.SegmentNumberEditFieldLabel = uilabel(app.ViewingParametersPanel);
            app.SegmentNumberEditFieldLabel.Enable = 'off';
            app.SegmentNumberEditFieldLabel.Visible = 'off';
            app.SegmentNumberEditFieldLabel.Text = 'Segment Number ';
            app.SegmentNumberEditField = uieditfield(app.ViewingParametersPanel, 'numeric');
            app.SegmentNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentNumberEditFieldValueChanged, true);
            app.SegmentNumberEditField.Editable = 'off';
            app.SegmentNumberEditField.Enable = 'off';
            app.SegmentNumberEditField.Visible = 'off';
            app.max_segment_label = uilabel(app.ViewingParametersPanel);
            app.max_segment_label.Enable = 'off';
            app.max_segment_label.Visible = 'off';
            app.max_segment_label.Text = '';
            app.RECORDButton = uibutton(app.UIFigure, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonValueChanged, true);
            app.RECORDButton.Text = 'RECORD';
            app.CLEARDATAButton = uibutton(app.UIFigure, 'push');
            app.CLEARDATAButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARDATAButtonPushed, true);
            app.CLEARDATAButton.Text = 'CLEAR DATA';
            app.SaveDataPanel = uipanel(app.UIFigure);
            app.SaveDataPanel.Title = 'Save Data';
            app.FolderEditFieldLabel = uilabel(app.SaveDataPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.SaveDataPanel, 'text');
            app.SAVEButton = uibutton(app.SaveDataPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.MATCHPASSWORDButton = uibutton(app.UIFigure, 'push');
            app.MATCHPASSWORDButton.Text = 'MATCH PASSWORD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICIBICI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end